classdef cerebot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PronunciaCerebotLabel  matlab.ui.control.Label
        HolaEditField_2        matlab.ui.control.EditField
        HolaEditField          matlab.ui.control.EditField
        IdentificarButton      matlab.ui.control.Button
        Button                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        prueba  % Variable para almacenar los datos de audio
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Callback del botón "Grabar"
            app.Button.Enable = 'off';  % Deshabilitar el botón "Grabar"
            recObj = audiorecorder;
            app.HolaEditField_2.Value = "Grabando...";
            recordblocking(recObj, 3);
            app.HolaEditField_2.Value = "Fin de la grabación";
            prueba = getaudiodata(recObj);
            app.prueba = prueba;  % Guarda los datos de audio en la aplicación
            app.Button.Enable = 'on';  % Habilitar el botón "Grabar"
        end

        % Button pushed function: IdentificarButton
        function IdentificarButtonPushed(app, event)
            load energy.mat
            load zeros.mat
            load media.mat
            load estandar.mat
            load modelo.mat
            % Código para el botón "Identificar"
            app.IdentificarButton.Enable = 'off';
            prueba = app.prueba; % Recupera los datos de audio
            fs = 8000;
            windowLength = round(0.03*fs);
            overlapLength = round(0.025*fs);
            afe = audioFeatureExtractor(SampleRate=fs, ...
                Window=hamming(windowLength,"periodic"),OverlapLength=overlapLength, ...
                zerocrossrate=true,shortTimeEnergy=true,pitch=true,mfcc=true);
            
            featureMap = info(afe);
            
            features = [];
            energyThreshold = 0.1;
            zcrThreshold = 0.3;
            
            keepLen = round(length(prueba)/3);
            
            prueba = prueba(1:keepLen);
            
            feat = extract(afe,prueba);
            isSpeech = feat(:,featureMap.shortTimeEnergy) > energyThreshold;
            isVoiced = feat(:,featureMap.zerocrossrate) < zcrThreshold;
            
            voicedSpeech = isSpeech & isVoiced;
            
            feat(~voicedSpeech,:) = [];
            feat(:,[featureMap.zerocrossrate,featureMap.shortTimeEnergy]) = [];
            
            features = [features;feat];
            features = (features-M)./S;
            
            prediction0 = predict(trainedClassifier,features);
            prediction = mode(prediction0);
            
            % Mostrar la predicción en el campo de texto
            app.HolaEditField.Value = "Hola, " + char(prediction) + "...";
            app.IdentificarButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'grabar.png');
            app.Button.Text = '';
            app.IdentificarButton = uibutton(app.UIFigure, 'push');
            app.IdentificarButton.ButtonPushedFcn = createCallbackFcn(app, @IdentificarButtonPushed, true);
            app.IdentificarButton.Text = 'Identificar';
            app.HolaEditField = uieditfield(app.UIFigure, 'text');
            app.HolaEditField.Editable = 'off';
            app.HolaEditField_2 = uieditfield(app.UIFigure, 'text');
            app.HolaEditField_2.Editable = 'off';
            app.PronunciaCerebotLabel = uilabel(app.UIFigure);
            app.PronunciaCerebotLabel.Text = 'Pronuncia <Cerebot>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cerebot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end